classdef frame_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FrameEditField           matlab.ui.control.NumericEditField
        FrameEditFieldLabel      matlab.ui.control.Label
        RefreshButton            matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        FilterbyTrackCheckBox    matlab.ui.control.CheckBox
        ShowDetectionsCheckBox   matlab.ui.control.CheckBox
        ShowGroundTruthCheckBox  matlab.ui.control.CheckBox
        Slider                   matlab.ui.control.Slider
        SliderLabel              matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        him  % handle to the image
        hfd  % handle to fascicle detector
        hgt  % handle to the ground truth
        hr   % handle to the results
        keepInds

        hcrop
    end
    
    methods (Access = private, Static)        
        function A = load_image(frame)
            frame = min(8500, max(1, round(frame)));
            imgName = @(frame) sprintf('D:\\Images 1000-8500\\HVRLN__rec%08d.tif', frame);
            A = imread(imgName(frame));            
            A = uint8(255 * double(A) / (2^16 - 1));
            A = repmat(A, [1 1 3]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % display the initial image
            app.Slider.Value = app.FrameEditField.Value;

            frame = app.Slider.Value;
            app.him = imagesc(app.UIAxes, app.load_image(frame));
            xlim(app.UIAxes, [0 2000]);
            ylim(app.UIAxes, [0 2000]);
            
            % instantiate the fascicle detector
            app.hfd = FascicleMaskRCNN('human');

            % load the ground truth
            app.Label.Text = 'Loading Ground Truth'; drawnow();
            load('mygt.mat', 'gtmasks', 'gtlabels', 'gtboxes', 'gtCentroidTable');
            app.hgt = FascicleResults('mygt', app.hfd.allframes, gtmasks, gtboxes, gtlabels, [], gtCentroidTable);

            % load the results            
            resultsFiles = arrayfun(@(x) sprintf('mat files\\fold%d\\myresults_fold%d.mat', x, x), 1:5, 'UniformOutput', false);
            [gndtruthframes, frames, masks, labels, scores, boxes, mytable] = deal([]);
            for ifold = 1:length(resultsFiles)                
                app.Label.Text = sprintf('Loading Detections Fold %d', ifold); drawnow();
                
                [~, gndtruthframes_, frames_] = app.hfd.get_trainInds(ifold);
                gndtruthframes_ = gndtruthframes_(ismember(gndtruthframes_, frames_));  % stage 2 training contains 1 extra frame                    
                temp = load(resultsFiles{ifold}, 'masks', 'labels', 'scores', 'boxes', 'mytable');                

                % concatenate folds
                gndtruthframes = [gndtruthframes gndtruthframes_];
                frames = [frames frames_];
                masks = [masks temp.masks];
                labels = [labels temp.labels];
                boxes = [boxes temp.boxes];
                scores = [scores temp.scores];
                mytable = [mytable; temp.mytable];                
            end
            app.hr = replace_detections_with_ground_truth(app.hgt, gndtruthframes, sprintf('fold%d', ifold), frames, masks, labels, scores, boxes, mytable);
            
            % load the tracks
            app.Label.Text = 'Loading Tracks'; drawnow();            
            tracksFile = 'mat files\combined\tracks_combined.mat';           
            load(tracksFile, 'tracks');
            app.keepInds = app.hr.filter_by_track(tracks, 30, gndtruthframes, false);

            app.Label.Text = 'Ready'; drawnow();

            app.hcrop = images.roi.Rectangle(app.UIAxes, 'Position', [625 625 750 750], 'FaceAlpha', 0.5, 'Color', [200 200 0] / 255);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            frame = round(app.Slider.Value);
            imgName = @(frame) sprintf('D:\\Images 1000-8500\\HVRLN__rec%08d.tif', frame);

            clearflag = true;
            % TODO draw to the correct axes
            if ismember(frame, app.hr.frames)
                if app.ShowGroundTruthCheckBox.Value
                    app.hgt.show_detection_img(app.UIAxes, frame, imgName(frame), 2, [], clearflag);
                    clearflag = false;
                end
                if app.ShowDetectionsCheckBox.Value
                    if app.FilterbyTrackCheckBox.Value
                        app.hr.show_detection_img(app.UIAxes, frame, imgName(frame), 1, app.keepInds, clearflag);
                        clearflag = false;
                    else
                        app.hr.show_detection_img(app.UIAxes, frame, imgName(frame), 1, true(size(app.keepInds)), clearflag);
                        clearflag = false;
                    end
                end
                if ~app.ShowGroundTruthCheckBox.Value && ~app.ShowDetectionsCheckBox.Value
                    delete(findobj(app.UIAxes, 'Type', 'Text'));
                    if isvalid(app.him)
                        app.him.CData = app.load_image(frame);
                    else
                        app.him = imagesc(app.UIAxes, app.load_image(frame));
                    end
                end
            elseif isvalid(app.him)
                if app.ShowGroundTruthCheckBox.Value
                    app.hgt.show_detection_img(app.UIAxes, frame, imgName(frame), 2, [], clearflag);
                    clearflag = false;
                else
                    app.him.CData = app.load_image(frame);                
                end
            else                
                app.him = imagesc(app.UIAxes, app.load_image(frame));
            end            
            caxis(app.UIAxes, [0 255]);
            title(app.UIAxes, sprintf('Frame %d, [%d %d %d %d]', frame, round(app.hcrop.Position)));
            drawnow();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'downarrow'
                    if app.Slider.Value > 1000
                        app.FrameEditField.Value = app.Slider.Value - 1;
                        app.Slider.Value = app.Slider.Value - 1;
                        app.SliderValueChanged([]);
                    end
                case 'uparrow'
                    if app.Slider.Value < 8500
                        app.FrameEditField.Value = app.Slider.Value + 1;
                        app.Slider.Value = app.Slider.Value + 1;
                        app.SliderValueChanged([]);
                    end
            end            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            caxis(app.UIAxes, [0 1]);
            pause(.1);
            caxis(app.UIAxes, [0 255]);
        end

        % Value changed function: ShowGroundTruthCheckBox
        function ShowGroundTruthCheckBoxValueChanged(app, event)
            app.SliderValueChanged([]);           
        end

        % Value changed function: ShowDetectionsCheckBox
        function ShowDetectionsCheckBoxValueChanged(app, event)
            app.SliderValueChanged([]);
        end

        % Value changed function: FilterbyTrackCheckBox
        function FilterbyTrackCheckBoxValueChanged(app, event)
            app.SliderValueChanged([]);
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            app.FrameEditField.Value = min(8500, max(1000, round(app.FrameEditField.Value)));
            app.Slider.Value = app.FrameEditField.Value;
            app.SliderValueChanged([]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'axes_image';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1000 8500];
            app.Slider.MajorTicks = [1000 2500 4000 5500 7000 8500];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1000;
            app.ShowGroundTruthCheckBox = uicheckbox(app.UIFigure);
            app.ShowGroundTruthCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGroundTruthCheckBoxValueChanged, true);
            app.ShowGroundTruthCheckBox.Text = 'Show Ground Truth';
            app.ShowDetectionsCheckBox = uicheckbox(app.UIFigure);
            app.ShowDetectionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDetectionsCheckBoxValueChanged, true);
            app.ShowDetectionsCheckBox.Text = 'Show Detections';
            app.FilterbyTrackCheckBox = uicheckbox(app.UIFigure);
            app.FilterbyTrackCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterbyTrackCheckBoxValueChanged, true);
            app.FilterbyTrackCheckBox.Text = 'Filter by Track';
            app.Label = uilabel(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Value = 7000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frame_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end